classdef G8RobotArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        ReadDataMenu        matlab.ui.container.Menu
        DifferentiateMenu   matlab.ui.container.Menu
        CreateRobotMenu     matlab.ui.container.Menu
        HardwareMenu        matlab.ui.container.Menu
        InitArduinoMenu     matlab.ui.container.Menu
        TestArduinoMenu     matlab.ui.container.Menu
        UIAxes              matlab.ui.control.UIAxes
        InfoTextAreaLabel   matlab.ui.control.Label
        InfoTextArea        matlab.ui.control.TextArea
        DataReadLabel       matlab.ui.control.Label
        DataReadLamp        matlab.ui.control.Lamp
        Joint1SpinnerLabel  matlab.ui.control.Label
        Joint1Spinner       matlab.ui.control.Spinner
        Joint2SpinnerLabel  matlab.ui.control.Label
        Joint2Spinner       matlab.ui.control.Spinner
        Joint3SpinnerLabel  matlab.ui.control.Label
        Joint3Spinner       matlab.ui.control.Spinner
        Joint4SpinnerLabel  matlab.ui.control.Label
        Joint4Spinner       matlab.ui.control.Spinner
        Joint5SpinnerLabel  matlab.ui.control.Label
        Joint5Spinner       matlab.ui.control.Spinner
        Joint6SpinnerLabel  matlab.ui.control.Label
        Joint6Spinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        DataRead = false % Initialize as false
        Data = [] %buffer for reading data
        DiffData = []
        arduino_hardware = [];
        arduino_initialized = true;
        robot = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: InitArduinoMenu
        function Initialize(app, event)
            try
                app.arduino_hardware = arduino();
            catch
                msgbox('error on hardware initialize');
                return;
            end
            app.arduino_initialized = true;
            msgbox('Arduino initialized');
        end

        % Menu selected function: TestArduinoMenu
        function TestArduino(app, event)
            if(app.arduino_initialized)
                app.arduino_hardware.writeDigitalPin("D13",1);
            end
        end

        % Menu selected function: ReadDataMenu
        function ReadData(app, event)
            [fn pn] = uigetfile('*.txt', 'Where is the data?');
            filename = [pn fn];
            app.Data = textread(filename);
            plot(app.UIAxes, app.Data(:,1),app.Data(:,2));
            app.DataRead = true;
            app.DataReadLamp.Color = [0.0 1.0 0.0];
            app.InfoTextArea.Value = 'Data has been read.';
        end

        % Menu selected function: DifferentiateMenu
        function Differentiate(app, event)
            if(app.DataRead)
                app.DiffData = app.Data;
                app.DiffData(1:999,2) = diff(app.Data(:,2));
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.DiffData(:,1),app.DiffData(:,2),'g');
                app.InfoTextArea.Value = 'Green is the differential';
            else
                app.InfoTextArea.Value = 'Read the data first';
            end
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            G8_RobotArm;
            app.robot = G8;

            app.robot.plot([app.Joint1Spinner.Value ...
                app.Joint2Spinner.Value ...
                app.Joint3Spinner.Value]);

        end

        % Value changed function: Joint1Spinner
        function MoveJoint1(app, event)
            app.robot.plot([app.Joint1Spinner.Value ...
                app.Joint2Spinner.Value ...
                app.Joint3Spinner.Value]);
            
        end

        % Value changed function: Joint2Spinner
        function MoveJoint2(app, event)
            app.robot.plot([app.Joint1Spinner.Value ...
                app.Joint2Spinner.Value ...
                app.Joint3Spinner.Value]);
        end

        % Value changed function: Joint3Spinner
        function MoveJoint3(app, event)
            app.robot.plot([app.Joint1Spinner.Value ...
                app.Joint2Spinner.Value ...
                app.Joint3Spinner.Value]);
        end

        % Value changed function: Joint4Spinner
        function MoveJoint4(app, event)

        end

        % Value changed function: Joint5Spinner
        function MoveJoint5(app, event)

        end

        % Value changed function: Joint6Spinner
        function MoveJoint6(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadDataMenu = uimenu(app.FileMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadData, true);
            app.ReadDataMenu.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @Differentiate, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.CreateRobotMenu = uimenu(app.FileMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.InitArduinoMenu = uimenu(app.HardwareMenu);
            app.InitArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitArduinoMenu.Text = 'InitArduino';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @TestArduino, true);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plotarea')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.DataReadLabel = uilabel(app.UIFigure);
            app.DataReadLabel.Text = 'DataRead';
            app.DataReadLamp = uilamp(app.UIFigure);
            app.Joint1SpinnerLabel = uilabel(app.UIFigure);
            app.Joint1SpinnerLabel.Text = 'Joint1';
            app.Joint1Spinner = uispinner(app.UIFigure);
            app.Joint1Spinner.Step = 0.1;
            app.Joint1Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint1, true);
            app.Joint2SpinnerLabel = uilabel(app.UIFigure);
            app.Joint2SpinnerLabel.Text = 'Joint2';
            app.Joint2Spinner = uispinner(app.UIFigure);
            app.Joint2Spinner.Step = 0.1;
            app.Joint2Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint2, true);
            app.Joint3SpinnerLabel = uilabel(app.UIFigure);
            app.Joint3SpinnerLabel.Text = 'Joint3';
            app.Joint3Spinner = uispinner(app.UIFigure);
            app.Joint3Spinner.Step = 0.1;
            app.Joint3Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint3, true);
            app.Joint4SpinnerLabel = uilabel(app.UIFigure);
            app.Joint4SpinnerLabel.Text = 'Joint4';
            app.Joint4Spinner = uispinner(app.UIFigure);
            app.Joint4Spinner.Step = 0.1;
            app.Joint4Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint4, true);
            app.Joint5SpinnerLabel = uilabel(app.UIFigure);
            app.Joint5SpinnerLabel.Text = 'Joint5';
            app.Joint5Spinner = uispinner(app.UIFigure);
            app.Joint5Spinner.Step = 0.1;
            app.Joint5Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint5, true);
            app.Joint6SpinnerLabel = uilabel(app.UIFigure);
            app.Joint6SpinnerLabel.Text = 'Joint6';
            app.Joint6Spinner = uispinner(app.UIFigure);
            app.Joint6Spinner.Step = 0.1;
            app.Joint6Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint6, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = G8RobotArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end